classdef spike_sorting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        DataPathEditFieldLabel  matlab.ui.control.Label
        DataPathEditField       matlab.ui.control.EditField
        RunButton               matlab.ui.control.Button
        SelectClustersButton    matlab.ui.control.Button
        AddClustersButton       matlab.ui.control.Button
        ClusterxDropDownLabel   matlab.ui.control.Label
        ClusterxDropDown        matlab.ui.control.DropDown
        FunctionListBoxLabel    matlab.ui.control.Label
        FunctionListBox         matlab.ui.control.ListBox
        RunFunctionButton       matlab.ui.control.Button
        DeleteClustersButton    matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)

        %把所有的spikes都画出来
        function waveformsPlotting(app,option)
            global data;
            global c;
            global colors;
            global n;
            spikes_length = size(data.waveforms,2);
            spikes_max = max(data.waveforms,[],'all');
            spikes_min = min(data.waveforms,[],'all');
            
            switch option
                case 'Show Raw Spikes'
                    clf(app.UIAxes);
                    c = plot(app.UIAxes,data.waveforms','color','#0072BD');
                    k=length(c);
                    title(app.UIAxes,{'waveforms number:' k});
                    axis(app.UIAxes,[0 spikes_length spikes_min spikes_max]);
                case 'Show n'
                    cla(app.UIAxes);
                    
                    name = split(app.ClusterxDropDown.Value,' ');
                    m = str2double(name{2});

                    if isempty(colors)
                        plot(app.UIAxes,data.waveforms(data.idx == m,:)','color','#0072BD');
                    else
                        plot(app.UIAxes,data.waveforms(data.idx == m,:)','color',colors(m,:));
                    end
                    d = find(data.idx==m);
                    k = length(d);
                    title(app.UIAxes,{'waveforms number:' k});                    
                    axis(app.UIAxes,[0 spikes_length spikes_min spikes_max]);
                case 'Show All Clusters'
                    clf(app.UIAxes);
                    m = max(data.idx);
                    colors = distinguishable_colors(n,'w');
                    for i = 1:m
                        hold on;
                        n_index = data.idx == i;
                        plot(app.UIAxes,data.waveforms(n_index,:)','color',colors(i,:));
                        d = find(~data.idx);
                        k = length(d);
                        title(app.UIAxes,{'waveforms number:' k}); 
                        axis(app.UIAxes,[0 spikes_length spikes_min spikes_max]);
                    end
                case 'View n in data'
                    
                case 'View all in data'
                    
                case 'n''s frequency'
                    
                case 'all''s frequency'
                    
            end

        end

        %{
        %spikes重合的点不再重复画
        function waveformsPlotting(app)
            global data;
            spikes_length = size(data.waveforms,2);
            spikes_max = max(data.waveforms,[],'all');
            spikes_min = min(data.waveforms,[],'all');            
            for i = 1:spikes_length
                a = unique(data.waveforms(:,i));
                scatter(app.UIAxes,i*ones(size(a,1),1),a,".",'MarkerEdgeColor','#0072BD');
                hold on;
                axis([0 spikes_length spikes_min spikes_max]);
            end
            
        end
        
        %}
    end
    
    methods (Access = private)
        
        function addClusters(app)
            global n;
            global data;
            global rectangle;
            global c;
            spikes_length = size(data.waveforms,2);
            
            m = size(data.waveforms,1);
            t1=int16(rectangle.Position(1));
            t2=int16(rectangle.Position(1)+rectangle.Position(3));
            v1=rectangle.Position(2)*ones(1,t2-t1+1);
            v2=(rectangle.Position(2)+rectangle.Position(4))*ones(1,t2-t1+1);
            
            for i = 1:m
                if data.idx(i) ~= 0
                    continue;
                end
                
                if sum(data.waveforms(i,t1:t2) >= v1 & data.waveforms(i,t1:t2) <= v2) >= 1
                        delete(c(i));
                        data.idx(i) = n;
                end
            end
            rest = find(~data.idx);          
            spikes_max = max(data.waveforms(rest,:),[],'all');
            spikes_min = min(data.waveforms(rest,:),[],'all');
            axis(app.UIAxes,[0 spikes_length spikes_min spikes_max]);   
            delete(rectangle);
            k = length(rest);
            title(app.UIAxes,{'waveforms number:' k}); 
            axis(app.UIAxes,[0 spikes_length spikes_min spikes_max]);

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ClusterxDropDown.Items = {};
            addpath(genpath('Functions'))
            addpath(genpath('MyFunctions'))
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            global n;
            global flag;
            flag = 0;
            n = 0;
            
            if isempty(app.DataPathEditField.Value)
                fig = app.UIFigure;
                message = sprintf('path not found');
                uialert(fig,message,'Warning',...
                'Icon','warning');
            else
                d = uiprogressdlg(app.UIFigure,'Title','Preprocessing . . .',...
                'Indeterminate','on');
                
                spikeSorting(app.DataPathEditField.Value);
                waveformsPlotting(app,'Show Raw Spikes');
            end
     
            close(d);
        end

        % Button pushed function: SelectClustersButton
        function SelectClustersButtonPushed(app, event)
            global rectangle;
            global flag;
            global data;
            global c;
            
            if  flag==1
                rest = find(~data.idx);
                c(rest) = plot(app.UIAxes,data.waveforms(rest,:)','Color','#0072BD');
                spikes_length = size(data.waveforms,2);
                spikes_max = max(data.waveforms(rest,:),[],'all');
                spikes_min = min(data.waveforms(rest,:),[],'all');  
                k=length(rest);
                title(app.UIAxes,{'waveforms number:' k});
                axis(app.UIAxes,[0 spikes_length spikes_min spikes_max]); 
            end 
            flag=2;
            rectangle = drawrectangle(app.UIAxes,'Color','r');
        end

        % Button pushed function: AddClustersButton
        function AddClustersButtonPushed(app, event)
            global n;
            global flag; 
            if flag==2
                n=n+1;
               addClusters(app);
               app.ClusterxDropDown.Items{end+1} = ['Cluster ' int2str(n)];
         
            end
            flag=0;
        end

        % Button pushed function: RunFunctionButton
        function RunFunctionButtonPushed(app, event)
            value = app.FunctionListBox.Value;
            
            global flag
            flag=1;              
            switch value
                case 'Show Raw Spikes'
                    waveformsPlotting(app,'Show Raw Spikes');
                case 'Show x'
                    waveformsPlotting(app,"Show n");
                case 'Show All Clusters'
                    waveformsPlotting(app,"Show All Clusters");
                case 'View n in data'
                    
                case 'View all in data'
                    
                case 'n''s frequency'
                    
                case 'all''s frequency'
            end         
        end

        % Value changed function: DataPathEditField
        function DataPathEditFieldValueChanged(app, event)
            value = app.DataPathEditField.Value;            
        end

        % Value changed function: ClusterxDropDown
        function ClusterxDropDownValueChanged(app, event)
            value = app.ClusterxDropDown.Value; 
        end

        % Button pushed function: DeleteClustersButton
        function DeleteClustersButtonPushed(app, event)
           global data;
           global flag;
            flag=1;
            name = split(app.ClusterxDropDown.Value,' ');
            m= str2double(name{2});           
            data.idx(data.idx==m)=0;                
            data.idx(data.idx>m)=data.idx(data.idx>m)-1;  
            app.ClusterxDropDown.Items(:,end)=[];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {446, 446};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataPathEditFieldLabel = uilabel(app.LeftPanel);
            app.DataPathEditFieldLabel.Text = 'Data Path';
            app.DataPathEditField = uieditfield(app.LeftPanel, 'text');
            app.DataPathEditField.ValueChangedFcn = createCallbackFcn(app, @DataPathEditFieldValueChanged, true);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectClustersButton = uibutton(app.LeftPanel, 'push');
            app.SelectClustersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectClustersButtonPushed, true);
            app.SelectClustersButton.Text = 'Select Clusters';
            app.AddClustersButton = uibutton(app.LeftPanel, 'push');
            app.AddClustersButton.ButtonPushedFcn = createCallbackFcn(app, @AddClustersButtonPushed, true);
            app.AddClustersButton.Text = 'Add Clusters';
            app.ClusterxDropDownLabel = uilabel(app.LeftPanel);
            app.ClusterxDropDownLabel.Text = 'Cluster x';
            app.ClusterxDropDown = uidropdown(app.LeftPanel);
            app.ClusterxDropDown.Items = {};
            app.ClusterxDropDown.ValueChangedFcn = createCallbackFcn(app, @ClusterxDropDownValueChanged, true);
            app.ClusterxDropDown.Value = {};
            app.FunctionListBoxLabel = uilabel(app.LeftPanel);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.LeftPanel);
            app.FunctionListBox.Items = {'Show Raw Spikes', 'Show x', 'Show All Clusters', 'View x in data', 'View all in data', 'n''s frequency', 'all''s frequency'};
            app.FunctionListBox.Value = 'Show Raw Spikes';
            app.RunFunctionButton = uibutton(app.LeftPanel, 'push');
            app.RunFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @RunFunctionButtonPushed, true);
            app.RunFunctionButton.Text = 'Run Function';
            app.DeleteClustersButton = uibutton(app.LeftPanel, 'push');
            app.DeleteClustersButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteClustersButtonPushed, true);
            app.DeleteClustersButton.Text = 'Delete Clusters';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage (mV)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spike_sorting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end